classdef guiGap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigurationTab                matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        GridLayout8                     matlab.ui.container.GridLayout
        DisplayGapdurationCheckBox      matlab.ui.control.CheckBox
        PloteachstimgraphCheckBox       matlab.ui.control.CheckBox
        VolunteerNameorstudyIDEditField  matlab.ui.control.EditField
        VolunteerNameorstudyIDEditFieldLabel  matlab.ui.control.Label
        TestedEarButtonGroup            matlab.ui.container.ButtonGroup
        LeftButton                      matlab.ui.control.RadioButton
        BinauralButton                  matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        CalibrationPanel                matlab.ui.container.Panel
        GridLayout2_4                   matlab.ui.container.GridLayout
        LeftcalibrationdBEditFieldLabel  matlab.ui.control.Label
        LeftcalibrationdBEditField      matlab.ui.control.NumericEditField
        StartNewCalibrationButton       matlab.ui.control.Button
        RightcalibrationdBEditFieldLabel  matlab.ui.control.Label
        RightcalibrationdBEditField     matlab.ui.control.NumericEditField
        PsychometricconfigurationPanel  matlab.ui.container.Panel
        GridLayout2_3                   matlab.ui.container.GridLayout
        GaprepetitionnumberEditFieldLabel  matlab.ui.control.Label
        GaprepetitionnumberEditField    matlab.ui.control.NumericEditField
        TrialnumberEditFieldLabel       matlab.ui.control.Label
        TrialnumberEditField            matlab.ui.control.NumericEditField
        GappresentationconfigurationPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DepthGapinEditFieldLabel        matlab.ui.control.Label
        DepthGapinEditField             matlab.ui.control.NumericEditField
        IntensityleveldBEditFieldLabel  matlab.ui.control.Label
        IntensityleveldBEditField       matlab.ui.control.NumericEditField
        RampGapdurationinmsEditFieldLabel  matlab.ui.control.Label
        RampGapdurationinmsEditField    matlab.ui.control.NumericEditField
        ProtectedGapareainmsEditFieldLabel  matlab.ui.control.Label
        ProtectedGapareainmsEditField   matlab.ui.control.NumericEditField
        RampnoisedurationinmsEditFieldLabel  matlab.ui.control.Label
        RampnoisedurationinmsEditField  matlab.ui.control.NumericEditField
        GapnumberEditFieldLabel         matlab.ui.control.Label
        GapnumberEditField              matlab.ui.control.NumericEditField
        TotalstimdurationinmsEditFieldLabel  matlab.ui.control.Label
        TotalstimdurationinmsEditField  matlab.ui.control.NumericEditField
        GlobalconfigurationPanel        matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        SamplingfrequencyHz             matlab.ui.control.EditField
        SamplingfrequencyHzLabel        matlab.ui.control.Label
        Loadedconfig                    matlab.ui.control.TextArea
        NotsupportedconfigurationLabel  matlab.ui.control.Label
        RefreshButton                   matlab.ui.control.Button
        SaveconfigurationButton         matlab.ui.control.Button
        LoadaconfigurationButton        matlab.ui.control.Button
        RamptypeDropDownLabel           matlab.ui.control.Label
        RamptypeDropDown                matlab.ui.control.DropDown
        NoisetypeDropDownLabel          matlab.ui.control.Label
        NoisetypeDropDown               matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        FindroughthresholdconfigurationPanel  matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        NbrepetitiontostopEditFieldLabel  matlab.ui.control.Label
        NbrepetitiontostopEditField     matlab.ui.control.NumericEditField
        StratingGapinmsEditFieldLabel   matlab.ui.control.Label
        StratingGapinmsEditField        matlab.ui.control.NumericEditField
        IncreasesetpinmsEditFieldLabel  matlab.ui.control.Label
        IncreasesetpinmsEditField       matlab.ui.control.NumericEditField
        DecreasestepinmsEditFieldLabel  matlab.ui.control.Label
        DecreasestepinmsEditField       matlab.ui.control.NumericEditField
        MaxgapinmsEditFieldLabel        matlab.ui.control.Label
        MaxgapinmsEditField             matlab.ui.control.NumericEditField
        TrialnumbermaxEditFieldLabel    matlab.ui.control.Label
        TrialnumbermaxEditField         matlab.ui.control.NumericEditField
        TrainingTab                     matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GappositionmsSlider             matlab.ui.control.Slider
        GappositionmsSliderLabel        matlab.ui.control.Label
        GapNumberSpinner                matlab.ui.control.Spinner
        GapNumberSpinnerLabel           matlab.ui.control.Label
        GapdurationmsSlider             matlab.ui.control.Slider
        GapdurationmsSliderLabel        matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        RoughthresholdTab               matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        RoughThresholdVal               matlab.ui.control.NumericEditField
        RoughThresholdmsEditFieldLabel  matlab.ui.control.Label
        StartRough                      matlab.ui.control.Button
        SaveRough                       matlab.ui.control.Button
        UIAxesRough                     matlab.ui.control.UIAxes
        GappsychometricTab              matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        StartPsycho                     matlab.ui.control.Button
        SavePsycho                      matlab.ui.control.Button
        UIAxesPsycho                    matlab.ui.control.UIAxes
        AboutTab                        matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        CreditEvaThurotMartaCampiGregoryGerentonLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CalibValue
        name
        ongoingTest = false;
        supportedConfig = 0;
        validSpecialChars = regexptranslate('escape','-_');
    end

    methods (Access = private)
        function setPosSliderLimits(app)
            %minGapShift = ceil(app.RampnoisedurationinmsEditField.Value + app.ProtectedGapareainmsEditField.Value + app.RampGapdurationinmsEditField.Value);
            minGapShift = 0;
            gap_area_sample = app.TotalstimdurationinmsEditField.Value ...
                - (app.GapNumberSpinner.Value*app.GapdurationmsSlider.Value) ...
                - (2*app.RampnoisedurationinmsEditField.Value)  ...
                - (2*app.GapNumberSpinner.Value*app.RampGapdurationinmsEditField.Value) ... 
                - ((app.GapNumberSpinner.Value+1)*app.ProtectedGapareainmsEditField.Value);
            maxGapShift = minGapShift + floor(gap_area_sample/app.GapNumberSpinner.Value);
            app.GappositionmsSlider.Limits = [minGapShift;maxGapShift];
        end

        function LoadEditFieldPropertie(app, data, propertieName, appParam)
            [~,index] = ismember(propertieName,data(1,:));
            if index
                appParam.Value = cell2mat(data(2,index));
            else
                msg = ['Unknown properties in the loaded file : ' propertieName];
                errordlg(msg,'Error');
            end
        end

        function LoadTextFieldPropertie(app, data, propertieName, appParam)
            [~,index] = ismember(propertieName,data(1,:));
            if index
                appParam.Value = num2str(cell2mat(data(2,index)));
            else
                msg = ['Unknown properties in the loaded file : ' propertieName];
                errordlg(msg,'Error');
            end
        end

        function LoadDropDownPropertie(app, data, propertieName, appParam)
            [~,index] = ismember(propertieName,data(1,:));
            if index
                [~,indexDropDown] = ismember(data(2,index),appParam.Items);
                if ~isempty(appParam.ItemsData)
                    appParam.Value = cell2mat(appParam.ItemsData(indexDropDown));
                else
                    appParam.Value = appParam.Items(indexDropDown);
                end
            else
                msg = ['Unknown properties in the loaded file : ' propertieName];
                errordlg(msg,'Error');
            end
        end

        function LoadEarRadioPropertie(app, data, propertieName, appParam)
            [~,index] = ismember(propertieName,data(1,:));
            if index
                for i = 1:size(appParam.Children,1)
                    if ismember(appParam.Children(i).Text,data(2,index))
                        appParam.Children(i).Value = 1;
                    else
                        appParam.Children(i).Value = 0;
                    end
                end
            else
                msg = ['Unknown properties in the loaded file : ' propertieName];
                errordlg(msg,'Error');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RefreshButtonPushed()
            app.UIAxesRough.YLim = [0 100];
            app.NotsupportedconfigurationLabel.Visible = 'on';
            app.VolunteerNameorstudyIDEditFieldLabel.FontColor = 'red';
            app.DeviceDropDownValueChanged();
        end

        % Button pushed function: StartNewCalibrationButton
        function StartNewCalibrationButtonPushed(app, event)
            app.ongoingTest = true;
            app.StartNewCalibrationButton.Enable = "off";
            Calib = CalibrationGap;
            Calib.audioDeviceId = app.DeviceDropDown.Value;
            Calib.Fs = str2double(app.SamplingfrequencyHz.Value);
            Calib.used_noise = app.NoisetypeDropDown.Value;
            Calib.ramp_type = app.RamptypeDropDown.Value;
            Calib.intensity_level_dB = app.IntensityleveldBEditField.Value;
            Calib.ramp_noise_duration = app.RampnoisedurationinmsEditField.Value;
            app.CalibValue = Calib.run();
            app.RightcalibrationdBEditField.Value = app.CalibValue(1);
            app.LeftcalibrationdBEditField.Value = app.CalibValue(2);
            figure(app.UIFigure); % to force gui in front in case of error in the function
            app.StartNewCalibrationButton.Enable = "on";
            app.ongoingTest = false;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            audioOut = AudioTool;
            app.DeviceDropDown.Items = audioOut.listOutputDevicesName();
            app.DeviceDropDown.ItemsData = audioOut.getOutputDevicesId();
        end

        % Button pushed function: StartRough
        function StartRoughPushed(app, event)
            app.ongoingTest = true;
            app.StartRough.Enable = "off";
            FT = FindThreshold;
            FT.audioDeviceId = app.DeviceDropDown.Value;
            FT.Fs = str2double(app.SamplingfrequencyHz.Value);
            FT.used_noise = app.NoisetypeDropDown.Value;
            FT.ramp_type = app.RamptypeDropDown.Value;
            FT.tested_ear = app.TestedEarButtonGroup.SelectedObject.Text;
            FT.stim_duration = app.TotalstimdurationinmsEditField.Value;
            FT.gap_number = app.GapnumberEditField.Value;
            FT.ramp_noise_duration = app.RampnoisedurationinmsEditField.Value;
            FT.protect_gap_area = app.ProtectedGapareainmsEditField.Value;
            FT.ramp_gap_duration = app.RampGapdurationinmsEditField.Value;
            FT.intensity_level_dB = app.IntensityleveldBEditField.Value;
            FT.depth_gap_percent = app.DepthGapinEditField.Value;
            FT.trial_number_max = app.TrialnumbermaxEditField.Value;
            FT.max_gap_ms = app.MaxgapinmsEditField.Value;
            FT.decrease_inc_ms = app.DecreasestepinmsEditField.Value;
            FT.increase_inc_ms = app.IncreasesetpinmsEditField.Value;
            FT.gap_ms = app.StratingGapinmsEditField.Value;
            FT.Nb_Repeat_To_Stop = app.NbrepetitiontostopEditField.Value;
            FT.plot_graph = app.PloteachstimgraphCheckBox.Value;
            FT.display_Duration = app.DisplayGapdurationCheckBox.Value;
            app.CalibValue = [app.RightcalibrationdBEditField.Value,app.LeftcalibrationdBEditField.Value];
            app.RoughThresholdVal.Value = FT.run(app.name, app.CalibValue, app.UIAxesRough);
            if app.RoughThresholdVal.Value == 0
                app.RoughThresholdVal.Value = FT.decrease_inc_ms;
            end
            figure(app.UIFigure); % to force gui in front in case of error in the function
            app.StartRough.Enable = "on";
            app.SaveRough.Enable = "on";
            app.ongoingTest = false;
        end

        % Button pushed function: StartPsycho
        function StartPsychoPushed(app, event)
            app.ongoingTest = true;
            app.StartPsycho.Enable = "off";
            PP = PlotPsychometric;
            PP.audioDeviceId = app.DeviceDropDown.Value;
            PP.Fs = str2double(app.SamplingfrequencyHz.Value);
            PP.used_noise = app.NoisetypeDropDown.Value;
            PP.ramp_type = app.RamptypeDropDown.Value;
            PP.tested_ear = app.TestedEarButtonGroup.SelectedObject.Text;
            PP.stim_duration = app.TotalstimdurationinmsEditField.Value;
            PP.gap_number = app.GapnumberEditField.Value;
            PP.ramp_noise_duration = app.RampnoisedurationinmsEditField.Value;
            PP.protect_gap_area = app.ProtectedGapareainmsEditField.Value;
            PP.ramp_gap_duration = app.RampGapdurationinmsEditField.Value;
            PP.intensity_level_dB = app.IntensityleveldBEditField.Value;
            PP.depth_gap_percent = app.DepthGapinEditField.Value;
            PP.trial_number = app.TrialnumberEditField.Value;
            PP.nb_repeat = app.GaprepetitionnumberEditField.Value;
            PP.plot_graph = app.PloteachstimgraphCheckBox.Value;
            PP.display_Duration = app.DisplayGapdurationCheckBox.Value;
            app.CalibValue = [app.RightcalibrationdBEditField.Value,app.LeftcalibrationdBEditField.Value];
            PP.run(app.name, app.RoughThresholdVal.Value, app.CalibValue, app.UIAxesPsycho);
            figure(app.UIFigure); % to force gui in front in case of error in the function
            app.StartPsycho.Enable = "on";
            app.SavePsycho.Enable = "on";
            app.ongoingTest = false;
        end

        % Value changed function: DeviceDropDown, SamplingfrequencyHz
        function DeviceDropDownValueChanged(app, event)
            app.supportedConfig = audiodevinfo(0,app.DeviceDropDown.Value,str2double(app.SamplingfrequencyHz.Value),16,2);
            if app.supportedConfig == 0
                app.NotsupportedconfigurationLabel.Visible = 'on';
            else
                app.NotsupportedconfigurationLabel.Visible = 'off';
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Determine which tab was selected
            selectedTab = app.TabGroup.SelectedTab;
            % Look up the value of the paired checkbox
            % Each "case" is the title of a Tab and contains the pair checkbox.
            AccessibleTab = true;
            switch selectedTab.Title
                case 'Configuration'
                    if app.ongoingTest
                        AccessibleTab = false;
                    end
                case 'Training'
                    if (app.ongoingTest==true) || (app.supportedConfig==0) || (isempty(app.name))
                        AccessibleTab = false;
                    else
                        app.setPosSliderLimits();
                    end
                case 'Rough threshold'
                    if (app.ongoingTest==true) || (app.supportedConfig==0) || (isempty(app.name))
                        AccessibleTab = false;
                    end
                case 'Gap psychometric'
                    if (app.ongoingTest==true) || (app.supportedConfig==0) || (isempty(app.name)) || (app.RoughThresholdVal.Value==0)
                        AccessibleTab = false;
                    end
                case 'About'
                    if app.ongoingTest
                        AccessibleTab = false;
                    end
                otherwise
                    msg = ['Unknown tab : ' selectedTab.Title];
                    errordlg(msg,'Error');
            end
            % If paired checkbox is checked, return to the previously selected tab
            if ~AccessibleTab
                app.TabGroup.SelectedTab = event.OldValue;
            end
        end

        % Value changed function: VolunteerNameorstudyIDEditField
        function VolunteerNameorstudyIDEditFieldValueChanged(app, event)
            if isempty(app.VolunteerNameorstudyIDEditField.Value)
                app.VolunteerNameorstudyIDEditFieldLabel.FontColor = 'red';
                app.name = '';
            else
                if isempty(regexp(app.VolunteerNameorstudyIDEditField.Value, ['[^A-Za-z0-9' app.validSpecialChars ']']))
                    app.VolunteerNameorstudyIDEditFieldLabel.Text = 'Volunteer Name or study ID';
                    app.VolunteerNameorstudyIDEditFieldLabel.FontColor = 'black';
                    app.name = app.VolunteerNameorstudyIDEditField.Value;
                else
                    app.VolunteerNameorstudyIDEditFieldLabel.Text = 'Contains invalid characters';
                    app.VolunteerNameorstudyIDEditFieldLabel.FontColor = 'red';
                    app.name = '';
                end
            end
        end

        % Value changing function: VolunteerNameorstudyIDEditField
        function VolunteerNameorstudyIDEditFieldValueChanging(app, event)
            if isempty(event.Value)
                app.VolunteerNameorstudyIDEditFieldLabel.FontColor = 'red';
                app.name = '';
            else
                if isempty(regexp(event.Value, ['[^A-Za-z0-9' app.validSpecialChars ']']))
                    app.VolunteerNameorstudyIDEditFieldLabel.Text = 'Volunteer Name or study ID';
                    app.VolunteerNameorstudyIDEditFieldLabel.FontColor = 'black';
                else
                    app.VolunteerNameorstudyIDEditFieldLabel.Text = 'Contains invalid characters';
                    app.VolunteerNameorstudyIDEditFieldLabel.FontColor = 'red';
                    app.name = '';
                end
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            switch app.STARTButton.Text
                case 'START'
                    app.ongoingTest = true;
                    app.GapNumberSpinner.Enable = "off";
                    app.GapdurationmsSlider.Enable = "off";
                    app.GappositionmsSlider.Enable = "off";
                    app.STARTButton.Text = 'STOP';
                    GP = GapPresentation;
                    GP.audioDeviceId = app.DeviceDropDown.Value;
                    GP.Fs = str2double(app.SamplingfrequencyHz.Value);
                    GP.used_noise = app.NoisetypeDropDown.Value;
                    GP.ramp_type = app.RamptypeDropDown.Value;
                    GP.tested_ear = app.TestedEarButtonGroup.SelectedObject.Text;
                    GP.stim_duration = app.TotalstimdurationinmsEditField.Value;
                    GP.gap_number = app.GapNumberSpinner.Value;
                    GP.ramp_noise_duration = app.RampnoisedurationinmsEditField.Value;
                    GP.protect_gap_area = app.ProtectedGapareainmsEditField.Value;
                    %GP.protect_gap_area = (GP.stim_duration - 2*GP.ramp_noise_duration - (2*GP.gap_number)*GP.ramp_gap_duration - GP.gap_number*app.GapdurationmsSlider.Value)/(2*GP.gap_number);
                    GP.ramp_gap_duration = app.RampGapdurationinmsEditField.Value;
                    GP.intensity_level_dB = app.IntensityleveldBEditField.Value;
                    GP.depth_gap_percent = app.DepthGapinEditField.Value;
                    GP.init(1);
                    app.CalibValue = [app.RightcalibrationdBEditField.Value,app.LeftcalibrationdBEditField.Value];
                    GP.applyCalibration(app.CalibValue);
                    GP.gap_duration = app.GapdurationmsSlider.Value;
                    GP.playFixedGap(app.GappositionmsSlider.Value);
                    t = linspace(0, GP.stim_duration, length(GP.envelop));
                    plot(app.UIAxes, t, GP.envelop);
                    app.UIAxes.YLim = [-0.1 1.1];
                    app.STARTButton.Text = 'START';
                    app.ongoingTest = false;
                    app.GapNumberSpinner.Enable = "on";
                    app.GapdurationmsSlider.Enable = "on";
                    app.GappositionmsSlider.Enable = "on";
                case 'STOP'
                    app.STARTButton.Text = 'START';
            end
        end

        % Value changed function: GapNumberSpinner, GapdurationmsSlider
        function GapdurationmsSliderValueChanged(app, event)
            app.setPosSliderLimits();
        end

        % Button pushed function: SaveRough
        function SaveRoughButtonPushed(app, event)
            exportName = [pwd '\outFile\' app.name '_RoughThreshold.png'];
            exportgraphics(app.UIAxesRough,exportName,'Resolution',300)
        end

        % Button pushed function: SavePsycho
        function SavePsychoButtonPushed(app, event)
            exportName = [pwd '\outFile\' app.name '_Psychometric.png'];
            exportgraphics(app.UIAxesPsycho,exportName,'Resolution',300)
        end

        % Button pushed function: SaveconfigurationButton
        function SaveconfigurationButtonPushed(app, event)
            CellToStore = {
                'Hardware_Device',...
                'Sampling_frequency_Hz',...
                'Noise_type',...
                'Ramp_type',...
                'Total_stim_duration_in_ms',...
                'Gap_number',...
                'Ramp_noise_duration_in_ms',...
                'Protected_Gap_area_in_ms',...
                'Ramp_Gap_duration_in_ms',...
                'Intensity_level_dB',...
                'Depth_Gap_in',...
                'Trial_number_max',...
                'Max_gap_in_ms',...
                'Decrease_step_in_ms',...
                'Increase_setp_in_ms',...
                'Strating_Gap_in_ms',...
                'Nb_repetition_to_stop',...
                'Trial_number',...
                'Gap_repetition_nb',...
                'Right_calibration_dB',...
                'Left_calibration_dB',...
                'Tested_Ear',...
                'Plot_each_stim_graph',...
                'Display_Gap_duration'};
            [~,index] = ismember(app.DeviceDropDown.Value,cell2mat(app.DeviceDropDown.ItemsData));
            CellToStore=[CellToStore;...
                [app.DeviceDropDown.Items(index),...
                {app.SamplingfrequencyHz.Value,...
                app.NoisetypeDropDown.Value,...
                app.RamptypeDropDown.Value,...
                app.TotalstimdurationinmsEditField.Value,...
                app.GapnumberEditField.Value,...
                app.RampnoisedurationinmsEditField.Value,...
                app.ProtectedGapareainmsEditField.Value,...
                app.RampGapdurationinmsEditField.Value,...
                app.IntensityleveldBEditField.Value,...
                app.DepthGapinEditField.Value,...
                app.TrialnumbermaxEditField.Value,...
                app.MaxgapinmsEditField.Value,...
                app.DecreasestepinmsEditField.Value,...
                app.IncreasesetpinmsEditField.Value,...
                app.StratingGapinmsEditField.Value,...
                app.NbrepetitiontostopEditField.Value,...
                app.TrialnumberEditField.Value,...
                app.GaprepetitionnumberEditField.Value,...
                app.RightcalibrationdBEditField.Value,...
                app.LeftcalibrationdBEditField.Value,...
                app.TestedEarButtonGroup.SelectedObject.Text,...
                app.PloteachstimgraphCheckBox.Value,...
                app.DisplayGapdurationCheckBox.Value}]];
            [file,path] = uiputfile('GapConfig_.csv','Define file name and folder to save your config');
            writecell(CellToStore,[path file]);
            app.Loadedconfig.Value = [path file];
        end

        % Button pushed function: LoadaconfigurationButton
        function LoadaconfigurationButtonPushed(app, event)
            [file,path] = uigetfile('*.csv','Select file to load your config');
            app.Loadedconfig.Value = [path file];
            data = readcell([path file]);
            app.LoadDropDownPropertie(data,'Hardware_Device', app.DeviceDropDown);
            app.LoadTextFieldPropertie(data,'Sampling_frequency_Hz', app.SamplingfrequencyHz);
            app.DeviceDropDownValueChanged();
            app.LoadDropDownPropertie(data,'Noise_type', app.NoisetypeDropDown);
            app.LoadDropDownPropertie(data,'Ramp_type', app.RamptypeDropDown);
            app.LoadEditFieldPropertie(data,'Total_stim_duration_in_ms', app.TotalstimdurationinmsEditField);
            app.LoadEditFieldPropertie(data,'Gap_number', app.GapnumberEditField);
            app.LoadEditFieldPropertie(data,'Ramp_noise_duration_in_ms', app.RampnoisedurationinmsEditField);
            app.LoadEditFieldPropertie(data,'Protected_Gap_area_in_ms', app.ProtectedGapareainmsEditField);
            app.LoadEditFieldPropertie(data,'Ramp_Gap_duration_in_ms', app.RampGapdurationinmsEditField);
            app.LoadEditFieldPropertie(data,'Intensity_level_dB', app.IntensityleveldBEditField);
            app.LoadEditFieldPropertie(data,'Depth_Gap_in', app.DepthGapinEditField);
            app.LoadEditFieldPropertie(data,'Trial_number_max', app.TrialnumbermaxEditField);
            app.LoadEditFieldPropertie(data,'Max_gap_in_ms', app.MaxgapinmsEditField);
            app.LoadEditFieldPropertie(data,'Decrease_step_in_ms', app.DecreasestepinmsEditField);
            app.LoadEditFieldPropertie(data,'Increase_setp_in_ms', app.IncreasesetpinmsEditField);
            app.LoadEditFieldPropertie(data,'Strating_Gap_in_ms', app.StratingGapinmsEditField);
            app.LoadEditFieldPropertie(data,'Nb_repetition_to_stop', app.NbrepetitiontostopEditField);
            app.LoadEditFieldPropertie(data,'Trial_number', app.TrialnumberEditField);
            app.LoadEditFieldPropertie(data,'Gap_repetition_nb', app.GaprepetitionnumberEditField);
            app.LoadEditFieldPropertie(data,'Right_calibration_dB', app.RightcalibrationdBEditField);
            app.LoadEditFieldPropertie(data,'Left_calibration_dB', app.LeftcalibrationdBEditField);
            app.LoadEarRadioPropertie(data,'Tested_Ear', app.TestedEarButtonGroup);
            app.LoadEditFieldPropertie(data,'Plot_each_stim_graph', app.PloteachstimgraphCheckBox);
            app.LoadEditFieldPropertie(data,'Display_Gap_duration', app.DisplayGapdurationCheckBox);
            figure(app.UIFigure); % to force gui in front in case of error in the function
        end

        % Value changing function: SamplingfrequencyHz
        function SamplingfrequencyHzValueChanging(app, event)
            app.supportedConfig = audiodevinfo(0,app.DeviceDropDown.Value,str2double(event.Value),16,2);
            if app.supportedConfig == 0
                app.NotsupportedconfigurationLabel.Visible = 'on';
            else
                app.NotsupportedconfigurationLabel.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.GridLayout7 = uigridlayout(app.ConfigurationTab);
            app.FindroughthresholdconfigurationPanel = uipanel(app.GridLayout7);
            app.FindroughthresholdconfigurationPanel.Title = 'Find rough threshold configuration';
            app.GridLayout2_2 = uigridlayout(app.FindroughthresholdconfigurationPanel);
            app.TrialnumbermaxEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.TrialnumbermaxEditField.Limits = [1 Inf];
            app.TrialnumbermaxEditField.ValueDisplayFormat = '%.0f';
            app.TrialnumbermaxEditField.Value = 30;
            app.TrialnumbermaxEditFieldLabel = uilabel(app.GridLayout2_2);
            app.TrialnumbermaxEditFieldLabel.Text = 'Trial number max';
            app.MaxgapinmsEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.MaxgapinmsEditField.Limits = [1 Inf];
            app.MaxgapinmsEditField.ValueDisplayFormat = '%.0f';
            app.MaxgapinmsEditField.Value = 100;
            app.MaxgapinmsEditFieldLabel = uilabel(app.GridLayout2_2);
            app.MaxgapinmsEditFieldLabel.Text = 'Max gap in ms';
            app.DecreasestepinmsEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.DecreasestepinmsEditField.Limits = [1 Inf];
            app.DecreasestepinmsEditField.ValueDisplayFormat = '%.0f';
            app.DecreasestepinmsEditField.Value = 5;
            app.DecreasestepinmsEditFieldLabel = uilabel(app.GridLayout2_2);
            app.DecreasestepinmsEditFieldLabel.Text = 'Decrease step in ms';
            app.IncreasesetpinmsEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.IncreasesetpinmsEditField.Limits = [1 Inf];
            app.IncreasesetpinmsEditField.ValueDisplayFormat = '%.0f';
            app.IncreasesetpinmsEditField.Value = 10;
            app.IncreasesetpinmsEditFieldLabel = uilabel(app.GridLayout2_2);
            app.IncreasesetpinmsEditFieldLabel.Text = 'Increase setp in ms';
            app.StratingGapinmsEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.StratingGapinmsEditField.Limits = [1 Inf];
            app.StratingGapinmsEditField.ValueDisplayFormat = '%.0f';
            app.StratingGapinmsEditField.Value = 60;
            app.StratingGapinmsEditFieldLabel = uilabel(app.GridLayout2_2);
            app.StratingGapinmsEditFieldLabel.Text = 'Strating Gap in ms';
            app.NbrepetitiontostopEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.NbrepetitiontostopEditField.Limits = [1 Inf];
            app.NbrepetitiontostopEditField.ValueDisplayFormat = '%.0f';
            app.NbrepetitiontostopEditField.Value = 2;
            app.NbrepetitiontostopEditFieldLabel = uilabel(app.GridLayout2_2);
            app.NbrepetitiontostopEditFieldLabel.Text = 'Nb repetition to stop';
            app.GlobalconfigurationPanel = uipanel(app.GridLayout7);
            app.GlobalconfigurationPanel.Title = 'Global configuration';
            app.GridLayout = uigridlayout(app.GlobalconfigurationPanel);
            app.DeviceDropDown = uidropdown(app.GridLayout);
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.GridLayout);
            app.DeviceDropDownLabel.Text = 'Device';
            app.NoisetypeDropDown = uidropdown(app.GridLayout);
            app.NoisetypeDropDown.Items = {'white', 'pink', 'brown', 'blue', 'purple'};
            app.NoisetypeDropDown.Value = 'white';
            app.NoisetypeDropDownLabel = uilabel(app.GridLayout);
            app.NoisetypeDropDownLabel.Text = 'Noise type';
            app.RamptypeDropDown = uidropdown(app.GridLayout);
            app.RamptypeDropDown.Items = {'linear', 'hanning'};
            app.RamptypeDropDown.Value = 'hanning';
            app.RamptypeDropDownLabel = uilabel(app.GridLayout);
            app.RamptypeDropDownLabel.Text = 'Ramp type';
            app.LoadaconfigurationButton = uibutton(app.GridLayout, 'push');
            app.LoadaconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaconfigurationButtonPushed, true);
            app.LoadaconfigurationButton.Text = 'Load a configuration';
            app.SaveconfigurationButton = uibutton(app.GridLayout, 'push');
            app.SaveconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigurationButtonPushed, true);
            app.SaveconfigurationButton.Text = 'Save configuration';
            app.RefreshButton = uibutton(app.GridLayout, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'refresh_Icon.png');
            app.RefreshButton.Text = '';
            app.NotsupportedconfigurationLabel = uilabel(app.GridLayout);
            app.NotsupportedconfigurationLabel.Text = {'Not supported'; 'configuration'};
            app.Loadedconfig = uitextarea(app.GridLayout);
            app.Loadedconfig.Editable = 'off';
            app.Loadedconfig.Value = {'Loaded config : default'};
            app.SamplingfrequencyHzLabel = uilabel(app.GridLayout);
            app.SamplingfrequencyHzLabel.Text = 'Sampling frequency Hz';
            app.SamplingfrequencyHz = uieditfield(app.GridLayout, 'text');
            app.SamplingfrequencyHz.InputType = 'digits';
            app.SamplingfrequencyHz.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.SamplingfrequencyHz.ValueChangingFcn = createCallbackFcn(app, @SamplingfrequencyHzValueChanging, true);
            app.SamplingfrequencyHz.Value = '48000';
            app.GappresentationconfigurationPanel = uipanel(app.GridLayout7);
            app.GappresentationconfigurationPanel.Title = 'Gap presentation configuration';
            app.GridLayout2 = uigridlayout(app.GappresentationconfigurationPanel);
            app.TotalstimdurationinmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TotalstimdurationinmsEditField.Limits = [1 Inf];
            app.TotalstimdurationinmsEditField.ValueDisplayFormat = '%.0f';
            app.TotalstimdurationinmsEditField.Value = 1000;
            app.TotalstimdurationinmsEditFieldLabel = uilabel(app.GridLayout2);
            app.TotalstimdurationinmsEditFieldLabel.Text = 'Total stim duration in ms';
            app.GapnumberEditField = uieditfield(app.GridLayout2, 'numeric');
            app.GapnumberEditField.Limits = [1 Inf];
            app.GapnumberEditField.ValueDisplayFormat = '%.0f';
            app.GapnumberEditField.Value = 1;
            app.GapnumberEditFieldLabel = uilabel(app.GridLayout2);
            app.GapnumberEditFieldLabel.Text = 'Gap number';
            app.RampnoisedurationinmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RampnoisedurationinmsEditField.Limits = [0 Inf];
            app.RampnoisedurationinmsEditField.Value = 20;
            app.RampnoisedurationinmsEditFieldLabel = uilabel(app.GridLayout2);
            app.RampnoisedurationinmsEditFieldLabel.Text = 'Ramp noise duration in ms';
            app.ProtectedGapareainmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ProtectedGapareainmsEditField.Limits = [0 Inf];
            app.ProtectedGapareainmsEditField.Value = 180;
            app.ProtectedGapareainmsEditFieldLabel = uilabel(app.GridLayout2);
            app.ProtectedGapareainmsEditFieldLabel.Text = 'Protected Gap area in ms';
            app.RampGapdurationinmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RampGapdurationinmsEditField.Limits = [0 Inf];
            app.RampGapdurationinmsEditField.Value = 0.25;
            app.RampGapdurationinmsEditFieldLabel = uilabel(app.GridLayout2);
            app.RampGapdurationinmsEditFieldLabel.Text = 'Ramp Gap duration in ms';
            app.IntensityleveldBEditField = uieditfield(app.GridLayout2, 'numeric');
            app.IntensityleveldBEditField.Limits = [0 Inf];
            app.IntensityleveldBEditField.ValueDisplayFormat = '%.0f';
            app.IntensityleveldBEditField.Value = 65;
            app.IntensityleveldBEditFieldLabel = uilabel(app.GridLayout2);
            app.IntensityleveldBEditFieldLabel.Text = 'Intensity level dB';
            app.DepthGapinEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DepthGapinEditField.Limits = [0 Inf];
            app.DepthGapinEditField.ValueDisplayFormat = '%.0f';
            app.DepthGapinEditField.Value = 100;
            app.DepthGapinEditFieldLabel = uilabel(app.GridLayout2);
            app.DepthGapinEditFieldLabel.Text = 'Depth Gap in %';
            app.PsychometricconfigurationPanel = uipanel(app.GridLayout7);
            app.PsychometricconfigurationPanel.Title = 'Psychometric configuration';
            app.GridLayout2_3 = uigridlayout(app.PsychometricconfigurationPanel);
            app.TrialnumberEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.TrialnumberEditField.Limits = [1 Inf];
            app.TrialnumberEditField.ValueDisplayFormat = '%.0f';
            app.TrialnumberEditField.Value = 100;
            app.TrialnumberEditFieldLabel = uilabel(app.GridLayout2_3);
            app.TrialnumberEditFieldLabel.Text = 'Trial number';
            app.GaprepetitionnumberEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.GaprepetitionnumberEditField.Limits = [1 Inf];
            app.GaprepetitionnumberEditField.ValueDisplayFormat = '%.0f';
            app.GaprepetitionnumberEditField.Value = 10;
            app.GaprepetitionnumberEditFieldLabel = uilabel(app.GridLayout2_3);
            app.GaprepetitionnumberEditFieldLabel.Text = 'Gap repetition number';
            app.CalibrationPanel = uipanel(app.GridLayout7);
            app.CalibrationPanel.Title = 'Calibration';
            app.GridLayout2_4 = uigridlayout(app.CalibrationPanel);
            app.RightcalibrationdBEditField = uieditfield(app.GridLayout2_4, 'numeric');
            app.RightcalibrationdBEditField.Limits = [0 Inf];
            app.RightcalibrationdBEditField.ValueDisplayFormat = '%.1f';
            app.RightcalibrationdBEditField.Value = 79.8;
            app.RightcalibrationdBEditFieldLabel = uilabel(app.GridLayout2_4);
            app.RightcalibrationdBEditFieldLabel.Text = 'Right calibration dB';
            app.StartNewCalibrationButton = uibutton(app.GridLayout2_4, 'push');
            app.StartNewCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewCalibrationButtonPushed, true);
            app.StartNewCalibrationButton.Text = 'Start New Calibration';
            app.LeftcalibrationdBEditField = uieditfield(app.GridLayout2_4, 'numeric');
            app.LeftcalibrationdBEditField.Limits = [0 Inf];
            app.LeftcalibrationdBEditField.ValueDisplayFormat = '%.1f';
            app.LeftcalibrationdBEditField.Value = 79.6;
            app.LeftcalibrationdBEditFieldLabel = uilabel(app.GridLayout2_4);
            app.LeftcalibrationdBEditFieldLabel.Text = 'Left calibration dB';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.TestedEarButtonGroup = uibuttongroup(app.GridLayout8);
            app.TestedEarButtonGroup.Title = 'Tested Ear';
            app.RightButton = uiradiobutton(app.TestedEarButtonGroup);
            app.RightButton.Text = 'Right';
            app.RightButton.Value = true;
            app.BinauralButton = uiradiobutton(app.TestedEarButtonGroup);
            app.BinauralButton.Text = 'Binaural';
            app.LeftButton = uiradiobutton(app.TestedEarButtonGroup);
            app.LeftButton.Text = 'Left';
            app.VolunteerNameorstudyIDEditFieldLabel = uilabel(app.GridLayout8);
            app.VolunteerNameorstudyIDEditFieldLabel.Text = 'Volunteer Name or study ID';
            app.VolunteerNameorstudyIDEditField = uieditfield(app.GridLayout8, 'text');
            app.VolunteerNameorstudyIDEditField.ValueChangedFcn = createCallbackFcn(app, @VolunteerNameorstudyIDEditFieldValueChanged, true);
            app.VolunteerNameorstudyIDEditField.ValueChangingFcn = createCallbackFcn(app, @VolunteerNameorstudyIDEditFieldValueChanging, true);
            app.PloteachstimgraphCheckBox = uicheckbox(app.GridLayout8);
            app.PloteachstimgraphCheckBox.Text = 'Plot each stim graph';
            app.DisplayGapdurationCheckBox = uicheckbox(app.GridLayout8);
            app.DisplayGapdurationCheckBox.Text = 'Display Gap duration';
            app.Image = uiimage(app.GridLayout7);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gap_sch.PNG');
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.GridLayout6 = uigridlayout(app.TrainingTab);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Stimulus Amplitude View')
            xlabel(app.UIAxes, 'Stimulus duration (ms)')
            ylabel(app.UIAxes, 'Amplitude factor')
            zlabel(app.UIAxes, 'Z')
            app.STARTButton = uibutton(app.GridLayout6, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.GapdurationmsSliderLabel = uilabel(app.GridLayout6);
            app.GapdurationmsSliderLabel.Text = 'Gap duration ms';
            app.GapdurationmsSlider = uislider(app.GridLayout6);
            app.GapdurationmsSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.GapdurationmsSlider.ValueChangedFcn = createCallbackFcn(app, @GapdurationmsSliderValueChanged, true);
            app.GapdurationmsSlider.Value = 30;
            app.GapNumberSpinnerLabel = uilabel(app.GridLayout6);
            app.GapNumberSpinnerLabel.Text = 'Gap Number';
            app.GapNumberSpinner = uispinner(app.GridLayout6);
            app.GapNumberSpinner.Limits = [1 10];
            app.GapNumberSpinner.ValueDisplayFormat = '%.0f';
            app.GapNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @GapdurationmsSliderValueChanged, true);
            app.GapNumberSpinner.Value = 2;
            app.GappositionmsSliderLabel = uilabel(app.GridLayout6);
            app.GappositionmsSliderLabel.Text = 'Gap position ms';
            app.GappositionmsSlider = uislider(app.GridLayout6);
            app.GappositionmsSlider.Value = 30;
            app.RoughthresholdTab = uitab(app.TabGroup);
            app.RoughthresholdTab.Title = 'Rough threshold';
            app.GridLayout5 = uigridlayout(app.RoughthresholdTab);
            app.UIAxesRough = uiaxes(app.GridLayout5);
            title(app.UIAxesRough, 'Rough Threshold')
            xlabel(app.UIAxesRough, 'Presentation number')
            ylabel(app.UIAxesRough, 'Gap duration (ms)')
            zlabel(app.UIAxesRough, 'Z')
            app.SaveRough = uibutton(app.GridLayout5, 'push');
            app.SaveRough.ButtonPushedFcn = createCallbackFcn(app, @SaveRoughButtonPushed, true);
            app.SaveRough.Enable = 'off';
            app.SaveRough.Text = 'Export figure in PNG';
            app.StartRough = uibutton(app.GridLayout5, 'push');
            app.StartRough.ButtonPushedFcn = createCallbackFcn(app, @StartRoughPushed, true);
            app.StartRough.Text = 'Start Test';
            app.RoughThresholdmsEditFieldLabel = uilabel(app.GridLayout5);
            app.RoughThresholdmsEditFieldLabel.Text = 'Rough Threshold (ms)';
            app.RoughThresholdVal = uieditfield(app.GridLayout5, 'numeric');
            app.RoughThresholdVal.Limits = [0 Inf];
            app.RoughThresholdVal.ValueDisplayFormat = '%.0f';
            app.GappsychometricTab = uitab(app.TabGroup);
            app.GappsychometricTab.Title = 'Gap psychometric';
            app.GridLayout5_2 = uigridlayout(app.GappsychometricTab);
            app.UIAxesPsycho = uiaxes(app.GridLayout5_2);
            title(app.UIAxesPsycho, 'Psychometric Gap function')
            xlabel(app.UIAxesPsycho, 'Gap duration (ms)')
            ylabel(app.UIAxesPsycho, 'Correct answer %')
            zlabel(app.UIAxesPsycho, 'Z')
            app.SavePsycho = uibutton(app.GridLayout5_2, 'push');
            app.SavePsycho.ButtonPushedFcn = createCallbackFcn(app, @SavePsychoButtonPushed, true);
            app.SavePsycho.Enable = 'off';
            app.SavePsycho.Text = 'Export figure in PNG';
            app.StartPsycho = uibutton(app.GridLayout5_2, 'push');
            app.StartPsycho.ButtonPushedFcn = createCallbackFcn(app, @StartPsychoPushed, true);
            app.StartPsycho.Text = 'Start Test';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout9 = uigridlayout(app.AboutTab);
            app.CreditEvaThurotMartaCampiGregoryGerentonLabel = uilabel(app.GridLayout9);
            app.CreditEvaThurotMartaCampiGregoryGerentonLabel.Interpreter = 'html';
            app.CreditEvaThurotMartaCampiGregoryGerentonLabel.Text = {'<font size="3"><b>CERIAH</b>'; '<b>Center for Research and Innovation in Human Audiology</b>'; '<font size="1">'; 'Eva Thurot<sup>1</sup>'; 'Marta Campi<sup>1</sup>'; 'Gregory Gerenton<sup>1,*</sup>'; ''; '<sup>1</sup>Institut Pasteur, Université Paris Cité, Inserm, Institut de l’Audition, F-75012 Paris, France'; '<sup>*</sup>Corresponding author. gregory.gerenton@pasteur.fr'; ''; '<i>This work was supported by a grant from Fondation Pour l’Audition (FPA) to Paul Avan (FPA IDA10)</i>'};
            app.Image2 = uiimage(app.GridLayout9);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'CERIAH_logo_fr.png');
            app.Label = uilabel(app.GridLayout9);
            app.Label.Interpreter = 'html';
            app.Label.Text = '<font size="1">This program is free software; you can redistribute it and/or modify it under the terms of the GNU General Public License as published by the Free Software Foundation; either version 3 of the License, or (at your option) any later version';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiGap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end